classdef HexComb < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                matlab.ui.Figure
    Button                  matlab.ui.control.Button
    Attempt5                matlab.ui.control.EditField
    Attempt4                matlab.ui.control.EditField
    Attempt3                matlab.ui.control.EditField
    Attempt2                matlab.ui.control.EditField
    Attempt1                matlab.ui.control.EditField
    AttemptsRemainingLabel  matlab.ui.control.Label
    UserColor6              matlab.ui.control.EditField
    UserColor5              matlab.ui.control.EditField
    UserColor4              matlab.ui.control.EditField
    UserColor3              matlab.ui.control.EditField
    UserColor2              matlab.ui.control.EditField
    UserColor1              matlab.ui.control.EditField
    NewGame                 matlab.ui.control.Button
    Title                   matlab.ui.control.Label
    PercentLabel            matlab.ui.control.Label
    ScoreLabel              matlab.ui.control.Label
    Reset                   matlab.ui.control.Button
    Submit                  matlab.ui.control.Button
    Score                   matlab.ui.control.Label
    Hex6                    matlab.ui.control.EditField
    Hex5                    matlab.ui.control.EditField
    Hex4                    matlab.ui.control.EditField
    Hex3                    matlab.ui.control.EditField
    Hex2                    matlab.ui.control.EditField
    Hex1                    matlab.ui.control.EditField
  end

    
    properties (Access = public)
        % The true hex code colors
        hexCode1
        hexCode2
        hexCode3
        hexCode4
        hexCode5
        hexCode6
        % Saved user hex codes so that 'Reset' can return what the user had
        % before
        Hex1save
        Hex2save
        Hex3save
        Hex4save
        Hex5save
        Hex6save
        % Logical
        submitClicked
        gameCompleted
        % Attempts
        numAttempts
    end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
            %Print game instructions
            msg = ["Welcome to Hex Comb - a color guessing game!";...
              " ";...
              "How to play:";...
              " Type a valid 6-digit hexadecimal code into each of the six color areas and then hit ''Submit''. The accuracy score for each color will be printed in the relevant box, and your overall score will be printed in the middle. The colors corresponding to your guesses will be displayed as smaller boxes in the bottom right corner of each color area. You can hit the ''Reset'' button to try again, or the ''New Game'' button to generate a new set of colors.";...
              " ";...
              "What is a hexadecimal code?";...
              " Hex codes are a way to represent colors using a base-16 system of integers 0-F. Each hex code has six digits. The first two digits represent the red color channel, the second two digits represent the green color channel, and the last two digits represent the blue color channel. Darker colors are indicated by low values--for example, #000000 is the color black. In contrast, bright colors are represented by high values--for example, #FFFFFF is the color white. To make pure red, the hex code is #FF0000, pure green is #00FF00, magenta is #FF00FF, etc. Give the game a try, and you will quickly become more familiar with how hex codes work.";...
              " ";...
              "Have fun!"];
            msgbox(msg,"Welcome!")

            %Generate hex codes and populate color areas
            hexCodes = generateHexCodes();
            app.hexCode1 = hexCodes{1};
            app.hexCode2 = hexCodes{2};
            app.hexCode3 = hexCodes{3};
            app.hexCode4 = hexCodes{4};
            app.hexCode5 = hexCodes{5};
            app.hexCode6 = hexCodes{6};
            app.Hex1.BackgroundColor = hexCodes{1};
            app.Hex2.BackgroundColor = hexCodes{2};
            app.Hex3.BackgroundColor = hexCodes{3};
            app.Hex4.BackgroundColor = hexCodes{4};
            app.Hex5.BackgroundColor = hexCodes{5};
            app.Hex6.BackgroundColor = hexCodes{6};

            %Turn off editing privileges for the UserColor boxes
            app.UserColor1.Editable = 'off';
            app.UserColor2.Editable = 'off';
            app.UserColor3.Editable = 'off';
            app.UserColor4.Editable = 'off';
            app.UserColor5.Editable = 'off';
            app.UserColor6.Editable = 'off';

            %Turn off editing privileges for the Remaining Attempts boxes
            app.Attempt1.Editable = 'off';
            app.Attempt2.Editable = 'off';
            app.Attempt3.Editable = 'off';
            app.Attempt4.Editable = 'off';
            app.Attempt5.Editable = 'off';

            %Update submitClicked var
            app.submitClicked = false;

            %Update numAttempts
            app.numAttempts = 0;

            %Update game completed
            app.gameCompleted = false;
    end

    % Button pushed function: NewGame
    function NewGameButtonPushed(app, event)
            dlgPrompt = 'Are you sure you want to start a new game? This cannot be undone.';
            dlgTitle = 'New Game';
            user_selection = questdlg(dlgPrompt,dlgTitle,'Yes','No','No');

            switch user_selection
                case 'Yes'
                  %Generate new colors
                    hexCodes = generateHexCodes();
                    app.hexCode1 = hexCodes{1};
                    app.hexCode2 = hexCodes{2};
                    app.hexCode3 = hexCodes{3};
                    app.hexCode4 = hexCodes{4};
                    app.hexCode5 = hexCodes{5};
                    app.hexCode6 = hexCodes{6};
                    app.Hex1.BackgroundColor = hexCodes{1};
                    app.Hex2.BackgroundColor = hexCodes{2};
                    app.Hex3.BackgroundColor = hexCodes{3};
                    app.Hex4.BackgroundColor = hexCodes{4};
                    app.Hex5.BackgroundColor = hexCodes{5};
                    app.Hex6.BackgroundColor = hexCodes{6};
                  %Reset the labels
                    hexLabels = {'Hex1','Hex2','Hex3','Hex4','Hex5','Hex6'};
                    for n = 1:length(hexLabels)
                     app.(hexLabels{n}).Value = '';
                    end
                  %Reset the score
                    app.Score.Text = '0';
                  %Update the user color boxes
                   app.UserColor1.BackgroundColor = '#000000';
                   app.UserColor2.BackgroundColor = '#000000';
                   app.UserColor3.BackgroundColor = '#000000';
                   app.UserColor4.BackgroundColor = '#000000';
                   app.UserColor5.BackgroundColor = '#000000';
                   app.UserColor6.BackgroundColor = '#000000';
                  %Set submitClicked var
                    app.submitClicked = false;
                case 'No'
                    return
                otherwise
                    return
            end

            %Update numAttempts
            app.numAttempts = 0;

            %Enable Submit and Rest buttons
            app.Submit.Enable = 'on';
            app.Reset.Enable = 'on';

            %Update attempt boxes
            app.Attempt1.BackgroundColor = '#262626';
            app.Attempt2.BackgroundColor = '#262626';
            app.Attempt3.BackgroundColor = '#262626';
            app.Attempt4.BackgroundColor = '#262626';
            app.Attempt5.BackgroundColor = '#262626';

            %Update game completed
            app.gameCompleted = false;
    end

    % Button pushed function: Reset
    function ResetButtonPushed(app, event)
            %Reset box area color values
            hexLabels = {'Hex1','Hex2','Hex3','Hex4','Hex5','Hex6'};
            if ~app.submitClicked
               for n = 1:length(hexLabels)
                app.(hexLabels{n}).Value = '';
               end
            else
               app.Hex1.Value = app.Hex1save;
               app.Hex2.Value = app.Hex2save;
               app.Hex3.Value = app.Hex3save;
               app.Hex4.Value = app.Hex4save;
               app.Hex5.Value = app.Hex5save;
               app.Hex6.Value = app.Hex6save;
            end

            %Reset score
             app.Score.Text = '0';

            %Update the user color boxes
             app.UserColor1.BackgroundColor = '#000000';
             app.UserColor2.BackgroundColor = '#000000';
             app.UserColor3.BackgroundColor = '#000000';
             app.UserColor4.BackgroundColor = '#000000';
             app.UserColor5.BackgroundColor = '#000000';
             app.UserColor6.BackgroundColor = '#000000';

            %Update submitClicked var
             app.submitClicked = false;
    end

    % Button pushed function: Submit
    function SubmitButtonPushed(app, event)
            %Check to make sure all 6 boxes are properly completed
            hexLabels = {'Hex1','Hex2','Hex3','Hex4','Hex5','Hex6'};
            for n = 1:length(hexLabels)
             if (app.(hexLabels{n}).Value == '#') | ...
                     (isempty(app.(hexLabels{n}).Value)) | ...
                     (length(app.(hexLabels{n}).Value) < 6) | ...
                     (length(app.(hexLabels{n}).Value) > 7)
                return
             end
            end

            %Save the user-defined hexcodes to a global variable
            hexSave = {'Hex1save','Hex2save','Hex3save','Hex4save',...
              'Hex5save','Hex6save'};
            for n = 1:length(hexLabels)
             app.(hexSave{n}) = app.(hexLabels{n}).Value;
            end

            %Kill function if a value is empty
            h1 = app.Hex1.Value;
            if length(h1) < 1
                return
            end

            %Evaluate the score for each color
              [~,~,~,finalScoreH1] = compareHexCodes(app.hexCode1,h1);
            h2 = app.Hex2.Value;
              [~,~,~,finalScoreH2] = compareHexCodes(app.hexCode2,h2);
            h3 = app.Hex3.Value;
              [~,~,~,finalScoreH3] = compareHexCodes(app.hexCode3,h3);
            h4 = app.Hex4.Value;
              [~,~,~,finalScoreH4] = compareHexCodes(app.hexCode4,h4);
            h5 = app.Hex5.Value;
              [~,~,~,finalScoreH5] = compareHexCodes(app.hexCode5,h5);
            h6 = app.Hex6.Value;
              [~,~,~,finalScoreH6] = compareHexCodes(app.hexCode6,h6);

            %Calculate and show the final score
            finalScoreTotal = (finalScoreH1 + finalScoreH2 + finalScoreH3 +...
                finalScoreH4 + finalScoreH5 + finalScoreH6) / 6;
            if round(finalScoreTotal,1) == 100.0
             app.Score.Text = sprintf('100');
            else
             app.Score.Text = sprintf('%0.1f',round(finalScoreTotal,1));
            end

            %Add the individual scores to the color areas
            finalScores = [finalScoreH1;finalScoreH2;finalScoreH3;...
                finalScoreH4;finalScoreH5;finalScoreH6];
            for n = 1:length(hexLabels)
             app.(hexLabels{n}).Value = [sprintf('%0.1f',finalScores(n)) '%'];
            end

            %Update the user color boxes
            app.UserColor1.BackgroundColor = ['#' app.Hex1save];
            app.UserColor2.BackgroundColor = ['#' app.Hex2save];
            app.UserColor3.BackgroundColor = ['#' app.Hex3save];
            app.UserColor4.BackgroundColor = ['#' app.Hex4save];
            app.UserColor5.BackgroundColor = ['#' app.Hex5save];
            app.UserColor6.BackgroundColor = ['#' app.Hex6save];

            %Update submitClicked var
            app.submitClicked = true;

            %Update number of attempets
            attempt = app.numAttempts;
            app.numAttempts = attempt+1;

            %Adjust remaining attempts boxes
            if app.numAttempts == 1
               app.Attempt1.BackgroundColor = '#FFFFFF';
            elseif app.numAttempts == 2
               app.Attempt2.BackgroundColor = '#FFFFFF';
            elseif app.numAttempts == 3
               app.Attempt3.BackgroundColor = '#FFFFFF';
            elseif app.numAttempts == 4
               app.Attempt4.BackgroundColor = '#FFFFFF';
            elseif app.numAttempts == 5
               app.gameCompleted = true;
               app.Attempt5.BackgroundColor = '#FFFFFF';
               user_input = questdlg('Would you like to start a new game?',...
                 'Game over',...
                 'Yes','No','No');
               switch user_input
                 case 'Yes'
                   app.NewGameButtonPushed;
                 case 'No'
                   app.Submit.Enable = 'off';
                   app.Reset.Enable = 'off';
                 otherwise
                   app.Submit.Enable = 'off';
                   app.Reset.Enable = 'off';
               end
               %Update statistics
               score = str2double(app.Score.Text);
               generateStatistics(score);
            end

    end

    % Button pushed function: Button
    function ButtonPushed(app, event)
      %Import statistics
      p = which('HexComb');
      p = p(1:end-13); % Removes HexComb.mlapp
      p = [p 'private\'];
      stats = readtable([p 'hexCombStatistics.xlsx'],'ReadVariableNames',true);
      scores = stats.Score;
      avgScore = mean(scores);
      displayStatistics(scores,avgScore)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.Hex1 = uieditfield(app.UIFigure, 'text');
      app.Hex1.CharacterLimits = [0 7];
      app.Hex1.Placeholder = '#000000';
      app.Hex2 = uieditfield(app.UIFigure, 'text');
      app.Hex2.CharacterLimits = [0 7];
      app.Hex2.Placeholder = '#000000';
      app.Hex3 = uieditfield(app.UIFigure, 'text');
      app.Hex3.CharacterLimits = [0 7];
      app.Hex3.Placeholder = '#000000';
      app.Hex4 = uieditfield(app.UIFigure, 'text');
      app.Hex4.CharacterLimits = [0 7];
      app.Hex4.Placeholder = '#000000';
      app.Hex5 = uieditfield(app.UIFigure, 'text');
      app.Hex5.CharacterLimits = [0 7];
      app.Hex5.Placeholder = '#000000';
      app.Hex6 = uieditfield(app.UIFigure, 'text');
      app.Hex6.CharacterLimits = [0 7];
      app.Hex6.Placeholder = '#000000';
      app.Score = uilabel(app.UIFigure);
      app.Score.Text = '0';
      app.Submit = uibutton(app.UIFigure, 'push');
      app.Submit.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
      app.Submit.Text = 'Submit';
      app.Reset = uibutton(app.UIFigure, 'push');
      app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
      app.Reset.Text = 'Reset';
      app.ScoreLabel = uilabel(app.UIFigure);
      app.ScoreLabel.Text = 'Score';
      app.PercentLabel = uilabel(app.UIFigure);
      app.PercentLabel.Text = '%';
      app.Title = uilabel(app.UIFigure);
      app.Title.Text = 'Hex Comb';
      app.NewGame = uibutton(app.UIFigure, 'push');
      app.NewGame.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
      app.NewGame.Text = 'New Game';
      app.UserColor1 = uieditfield(app.UIFigure, 'text');
      app.UserColor2 = uieditfield(app.UIFigure, 'text');
      app.UserColor3 = uieditfield(app.UIFigure, 'text');
      app.UserColor4 = uieditfield(app.UIFigure, 'text');
      app.UserColor5 = uieditfield(app.UIFigure, 'text');
      app.UserColor6 = uieditfield(app.UIFigure, 'text');
      app.AttemptsRemainingLabel = uilabel(app.UIFigure);
      app.AttemptsRemainingLabel.Text = 'Attempts Remaining';
      app.Attempt1 = uieditfield(app.UIFigure, 'text');
      app.Attempt2 = uieditfield(app.UIFigure, 'text');
      app.Attempt3 = uieditfield(app.UIFigure, 'text');
      app.Attempt4 = uieditfield(app.UIFigure, 'text');
      app.Attempt5 = uieditfield(app.UIFigure, 'text');
      app.Button = uibutton(app.UIFigure, 'push');
      app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
      app.Button.Icon = fullfile(pathToMLAPP, 'private', 'stats.png');
      app.Button.Tooltip = {'View Stats'};
      app.Button.Text = '';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = HexComb

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end